classdef ps2000aMultimeterApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        UnitsLabel_3           matlab.ui.control.Label
        UnitsLabel_2           matlab.ui.control.Label
        UnitsLabel             matlab.ui.control.Label
        ConnectedLamp          matlab.ui.control.Lamp
        ConnectedLabel         matlab.ui.control.Label
        Switch                 matlab.ui.control.RockerSwitch
        ModeSelectorKnob       matlab.ui.control.DiscreteKnob
        ModeSelectorKnobLabel  matlab.ui.control.Label
        ValueDisplayEditField  matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        ps2000aDeviceObj % device object for controling the scope
        status % status returns from functions
        configs % configuration values
        mode % masurement mode
        blockGroupObj % block object
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            [app.configs.ps2000aStructs, app.configs.ps2000aEnumInfo, ...
                app.configs.ps2000aWrapStructs, app.configs.ps2000aWrapEnuminfo] = ps2000aSetConfig();

            % Create a device object. 
            % The serial number can be specified as a second input parameter.
            app.ps2000aDeviceObj = icdevice('picotech_ps2000a_generic.mdd', '');
    
            % Connect device object to hardware.

            connect(app.ps2000aDeviceObj)

            %turn off all channels apart from A & B
            [app.status.setChA] = invoke(app.ps2000aDeviceObj, 'ps2000aSetChannel', 0, 1, 1, 8, 0.0);
            [app.status.setChB] = invoke(app.ps2000aDeviceObj, 'ps2000aSetChannel', 1, 1, 1, 8, 0.0);

            if (app.ps2000aDeviceObj.channelCount == PicoConstants.QUAD_SCOPE)
    
                [app.status.setChC] = invoke(app.ps2000aDeviceObj, 'ps2000aSetChannel', 2, 0, 1, 8, 0.0);
                [app.status.setChD] = invoke(app.ps2000aDeviceObj, 'ps2000aSetChannel', 3, 0, 1, 8, 0.0);
    
            end

            if app.ps2000aDeviceObj.DriverData.unitHandle > 0

                app.ConnectedLamp.Color = [0.00,1.00,0.00];
                
            end
            app.configs.timebaseIndex = 2;
            set(app.ps2000aDeviceObj, 'timebase', app.configs.timebaseIndex);

            app.blockGroupObj = get(app.ps2000aDeviceObj, 'Block');
            app.blockGroupObj = app.blockGroupObj(1);

            set(app.ps2000aDeviceObj,'numPostTriggerSamples', 200000);

            app.mode = 0;
            probeMessage = ["Channel A: x1 Passive Probe","Channel B: TA189 Current Clamp"];
            uialert(app.UIFigure, probeMessage ,"Probes")
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            %% Disconnect device
            % Disconnect device object from hardware.

            disconnect(app.ps2000aDeviceObj);
            delete(app.ps2000aDeviceObj);
            delete(app)
        end

        % Value changed function: ModeSelectorKnob
        function ModeSelectorKnobValueChanged(app, event)
            app.mode = app.ModeSelectorKnob.Value;
            %app.Switch.Value = 0;
            switch app.mode
                case 0
                    app.UnitsLabel.Visible='off';
                    app.UnitsLabel_2.Visible='off';
                    app.UnitsLabel_3.Visible='off';
                case 1
                    app.UnitsLabel.Visible='on';
                    app.UnitsLabel_2.Visible='off';
                    app.UnitsLabel_3.Visible='off';
                case 2
                    app.UnitsLabel.Visible='on';
                    app.UnitsLabel_2.Visible='off';
                    app.UnitsLabel_3.Visible='off';
                case 3
                    app.UnitsLabel.Visible='off';
                    app.UnitsLabel_2.Visible='on';
                    app.UnitsLabel_3.Visible='off';
                case 4
                    app.UnitsLabel.Visible='off';
                    app.UnitsLabel_2.Visible='on';
                    app.UnitsLabel_3.Visible='off';
                case 5
                    app.UnitsLabel.Visible='off';
                    app.UnitsLabel_2.Visible='off';
                    app.UnitsLabel_3.Visible='on';
                case 6
                    app.UnitsLabel.Visible='off';
                    app.UnitsLabel_2.Visible='off';
                    app.UnitsLabel_3.Visible='on';
            end
            app.ValueDisplayEditField.Value = 0;
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            while value==1
                switch app.mode
                    case 0
                        % off
                        %app.Switch.Value = 0;
                        value = app.Switch.Value;
                    case 1
                        % V DC
                        [app.status.runBlock] = invoke(app.blockGroupObj, 'runBlock', 0);
                        startIndex=0;
                        segmentIndex=0;
                        downsamplingRatio=1;
                        downsamplingRatioMode=app.configs.ps2000aEnumInfo.enPS2000ARatioMode.PS2000A_RATIO_MODE_NONE;
                        [~, ~, chA, ~] = invoke(app.blockGroupObj, 'getBlockData', startIndex, segmentIndex, ...
                                            downsamplingRatio, downsamplingRatioMode);
                        app.ValueDisplayEditField.Value = mean(chA);
                        %app.Switch.Value = 0;
                        value = app.Switch.Value;
                    case 2
                        % V AC RMS
                        [app.status.runBlock] = invoke(app.blockGroupObj, 'runBlock', 0);
                        startIndex=0;
                        segmentIndex=0;
                        downsamplingRatio=1;
                        downsamplingRatioMode=app.configs.ps2000aEnumInfo.enPS2000ARatioMode.PS2000A_RATIO_MODE_NONE;
                        [~, ~, chA, ~] = invoke(app.blockGroupObj, 'getBlockData', startIndex, segmentIndex, ...
                                            downsamplingRatio, downsamplingRatioMode);
                        app.ValueDisplayEditField.Value = rms(chA);
                        %app.Switch.Value = 0;
                        value = app.Switch.Value;
                    case 3
                        % A DC
                        % TA189 clamp used
                         [app.status.runBlock] = invoke(app.blockGroupObj, 'runBlock', 0);
                        startIndex=0;
                        segmentIndex=0;
                        downsamplingRatio=1;
                        downsamplingRatioMode=app.configs.ps2000aEnumInfo.enPS2000ARatioMode.PS2000A_RATIO_MODE_NONE;
                        [~, ~, ~, chB] = invoke(app.blockGroupObj, 'getBlockData', startIndex, segmentIndex, ...
                                            downsamplingRatio, downsamplingRatioMode);
                        chBCurrent = chB /100;
                        app.ValueDisplayEditField.Value = mean(chBCurrent);
                        %app.Switch.Value = 0;
                        value = app.Switch.Value;
                    case 4
                        % A AC RMS
                        % TA189 clamp used
                         [app.status.runBlock] = invoke(app.blockGroupObj, 'runBlock', 0);
                        startIndex=0;
                        segmentIndex=0;
                        downsamplingRatio=1;
                        downsamplingRatioMode=app.configs.ps2000aEnumInfo.enPS2000ARatioMode.PS2000A_RATIO_MODE_NONE;
                        [~, ~, ~, chB] = invoke(app.blockGroupObj, 'getBlockData', startIndex, segmentIndex, ...
                                            downsamplingRatio, downsamplingRatioMode);
                        chBCurrent = chB /100;
                        app.ValueDisplayEditField.Value = rms(chBCurrent);
                        %app.Switch.Value = 0;
                        value = app.Switch.Value;
                    case 5 
                        % Resistance DC
                        [app.status.runBlock] = invoke(app.blockGroupObj, 'runBlock', 0);
                        startIndex=0;
                        segmentIndex=0;
                        downsamplingRatio=1;
                        downsamplingRatioMode=app.configs.ps2000aEnumInfo.enPS2000ARatioMode.PS2000A_RATIO_MODE_NONE;
                        [~, ~, chA, chB] = invoke(app.blockGroupObj, 'getBlockData', startIndex, segmentIndex, ...
                                            downsamplingRatio, downsamplingRatioMode);
                        chBCurrent = chB /100;
                        resistance = mean(chA) / mean(chBCurrent);
                        app.ValueDisplayEditField.Value = resistance;
                        %app.Switch.Value = 0;
                        value = app.Switch.Value;
                    case 6
                        % Resistance AC RMS
                        [app.status.runBlock] = invoke(app.blockGroupObj, 'runBlock', 0);
                        startIndex=0;
                        segmentIndex=0;
                        downsamplingRatio=1;
                        downsamplingRatioMode=app.configs.ps2000aEnumInfo.enPS2000ARatioMode.PS2000A_RATIO_MODE_NONE;
                        [~, ~, chA, chB] = invoke(app.blockGroupObj, 'getBlockData', startIndex, segmentIndex, ...
                                            downsamplingRatio, downsamplingRatioMode);
                        chBCurrent = chB /100;
                        resistance = rms(chA) / rms(chBCurrent);
                        app.ValueDisplayEditField.Value = resistance;
                        %app.Switch.Value = 0;
                        value = app.Switch.Value;
                end
                drawnow;
                pause(1)
            end
            app.Switch.Value = 0;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ValueDisplayEditField = uieditfield(app.UIFigure, 'numeric');
            app.ValueDisplayEditField.ValueDisplayFormat = '%.3f';
            app.ValueDisplayEditField.AllowEmpty = 'on';
            app.ValueDisplayEditField.Editable = 'off';
            app.ValueDisplayEditField.Value = [];
            app.ModeSelectorKnobLabel = uilabel(app.UIFigure);
            app.ModeSelectorKnobLabel.Text = 'Mode Selector';
            app.ModeSelectorKnob = uiknob(app.UIFigure, 'discrete');
            app.ModeSelectorKnob.Items = {'Off', 'V DC', 'V AC RMS', 'A DC', 'A AC RMS', 'Resistance DC', 'Resistance AC RMS'};
            app.ModeSelectorKnob.ItemsData = [0 1 2 3 4 5 6];
            app.ModeSelectorKnob.ValueChangedFcn = createCallbackFcn(app, @ModeSelectorKnobValueChanged, true);
            app.ModeSelectorKnob.Value = 0;
            app.Switch = uiswitch(app.UIFigure, 'rocker');
            app.Switch.Items = {'Stopped', 'Running'};
            app.Switch.ItemsData = [0 1];
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 0;
            app.ConnectedLabel = uilabel(app.UIFigure);
            app.ConnectedLabel.Text = 'Connected ?';
            app.ConnectedLamp = uilamp(app.UIFigure);
            app.UnitsLabel = uilabel(app.UIFigure);
            app.UnitsLabel.Visible = 'off';
            app.UnitsLabel.Text = 'mV';
            app.UnitsLabel_2 = uilabel(app.UIFigure);
            app.UnitsLabel_2.Visible = 'off';
            app.UnitsLabel_2.Text = 'A';
            app.UnitsLabel_3 = uilabel(app.UIFigure);
            app.UnitsLabel_3.Visible = 'off';
            app.UnitsLabel_3.Text = 'mOhms';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ps2000aMultimeterApp

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end